classdef app5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        ButtonGroup                     matlab.ui.container.ButtonGroup
        vernderlicheDickeButton         matlab.ui.control.RadioButton
        konstanteDickeButton            matlab.ui.control.RadioButton
        AnsetztpunktneuButtonGroup      matlab.ui.container.ButtonGroup
        untenButton_2                   matlab.ui.control.RadioButton
        mittellinieButton_2             matlab.ui.control.RadioButton
        obenButton_2                    matlab.ui.control.RadioButton
        AnsetztpunktaltButtonGroup      matlab.ui.container.ButtonGroup
        mittellinieButton               matlab.ui.control.RadioButton
        untenButton                     matlab.ui.control.RadioButton
        obenButton                      matlab.ui.control.RadioButton
        DickeEndeEditField              matlab.ui.control.NumericEditField
        DickeEndeEditFieldLabel         matlab.ui.control.Label
        EndWinkelEditField              matlab.ui.control.NumericEditField
        EndWinkelEditFieldLabel         matlab.ui.control.Label
        AnfangsWinkelEditField          matlab.ui.control.NumericEditField
        AnfangsWinkelEditFieldLabel     matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.NumericEditField
        RadiusEditFieldLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        BerechnenButton                 matlab.ui.control.Button
        PositionSpinner                 matlab.ui.control.Spinner
        PositionSpinnerLabel            matlab.ui.control.Label
        AusgewhltesElementSpinner       matlab.ui.control.Spinner
        AusgewhltesElementSpinnerLabel  matlab.ui.control.Label
        HinzufgenButton                 matlab.ui.control.Button
        WinkelEditField                 matlab.ui.control.NumericEditField
        WinkelEditFieldLabel            matlab.ui.control.Label
        DickeAnfangEditField            matlab.ui.control.NumericEditField
        DickeAnfangEditFieldLabel       matlab.ui.control.Label
        LngeEditField                   matlab.ui.control.NumericEditField
        LngeEditFieldLabel              matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        EntfernenButton                 matlab.ui.control.Button
        FormButtonGroup                 matlab.ui.container.ButtonGroup
        KraftButton                     matlab.ui.control.RadioButton
        gebogenButton                   matlab.ui.control.RadioButton
        geradeButton                    matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        Tab2                            matlab.ui.container.Tab
        Image_2                         matlab.ui.control.Image
        GesamtflchenschwerpunktyEditField  matlab.ui.control.NumericEditField
        GesamtflchenschwerpunktyEditFieldLabel  matlab.ui.control.Label
        GesamtflchenschwerpunktxEditField  matlab.ui.control.NumericEditField
        GesamtflchenschwerpunktxEditFieldLabel  matlab.ui.control.Label
        FlchenschwerpunkteTextArea      matlab.ui.control.TextArea
        FlchenschwerpunkteTextAreaLabel  matlab.ui.control.Label
        StatusmeldungTextArea           matlab.ui.control.TextArea
        StatusmeldungTextAreaLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear global;

            %Wahrscheinlich nicht notwendig
            %An und Ausblenden von Eingabefelder beim Start
            global F;


             if app.geradeButton.Value == true;
                app.StatusmeldungTextArea.Value='F = 0';
                %gerade
                app.LngeEditField.Visible = 'on';
                app.LngeEditFieldLabel.Visible = 'on';
                %app.DickeAnfangEditField.Visible = 'on';
                %app.DickeAnfangEditFieldLabel.Visible = 'on';
                %app.DickeEndeEditField.Visible = 'on';
                %app.DickeEndeEditFieldLabel.Visible = 'on';
                app.WinkelEditField.Visible = 'on';
                app.WinkelEditFieldLabel.Visible = 'on';
                %app.AusgewhltesElementSpinner.Visible = 'on';
                %app.AusgewhltesElementSpinnerLabel.Visible = 'on';
                %app.AnsetzpunktaltSpinner.Visible = 'on';
                %app.AnsetzpunktaltSpinnerLabel.Visible = 'on';
                %app.PositionSpinner.Visible = 'on';
                %app.PositionSpinnerLabel.Visible = 'on';
                %app.AnsetzpunktneuSpinner.Visible = 'on';
                %app.AnsetzpunktneuSpinnerLabel .Visible= 'on';
                %gebogen
                app.RadiusEditField.Visible = 'off';
                app.RadiusEditFieldLabel.Visible = 'off';
                app.AnfangsWinkelEditField.Visible = 'off';
                app.AnfangsWinkelEditFieldLabel.Visible = 'off';
                app.EndWinkelEditField.Visible = 'off';
                app.EndWinkelEditFieldLabel.Visible = 'off';


            elseif app.gebogenButton.Value == true;
                app.StatusmeldungTextArea.Value='F = 1';
                %gerade
                app.LngeEditField.Visible = 'off';
                app.LngeEditFieldLabel.Visible = 'off';
                %app.DickeAnfangEditField.Visible = 'off';
                %app.DickeAnfangEditFieldLabel.Visible = 'off';
                %app.DickeEndeEditField.Visible = 'off';
                %app.DickeEndeEditFieldLabel.Visible = 'off';
                app.WinkelEditField.Visible = 'off';
                app.WinkelEditFieldLabel.Visible = 'off';
                %app.AusgewhltesElementSpinner.Visible = 'off';
                %app.AusgewhltesElementSpinnerLabel.Visible = 'off';
                %app.AnsetzpunktaltSpinner.Visible = 'off';
                %app.AnsetzpunktaltSpinnerLabel.Visible = 'off';
                %app.PositionSpinner.Visible = 'off';
                %app.PositionSpinnerLabel.Visible = 'off';
                %app.AnsetzpunktneuSpinner.Visible = 'off';
                %app.AnsetzpunktneuSpinnerLabel.Visible = 'off';
                %gebogen
                app.RadiusEditField.Visible = 'on';
                app.RadiusEditFieldLabel.Visible = 'on';
                app.AnfangsWinkelEditField.Visible = 'on';
                app.AnfangsWinkelEditFieldLabel.Visible = 'on';
                app.EndWinkelEditField.Visible = 'on';
                app.EndWinkelEditFieldLabel.Visible = 'on';

             end

             app.DickeEndeEditField.Visible = "off";
             app.DickeEndeEditFieldLabel.Visible = "off";


        end

        % Callback function
        function FormButtonGroupButtonDown(app, event)

        end

        % Value changed function: WinkelEditField
        function WinkelEditFieldValueChanged(app, event)
            global W;
            
            value = app.WinkelEditField.Value;
            W = value;

           app.StatusmeldungTextArea.Value=[app.StatusmeldungTextArea.Value;'W gesetzt'];
        end

        % Value changed function: DickeAnfangEditField
        function DickeAnfangEditFieldValueChanged(app, event)
           global t;
            
            value = app.DickeAnfangEditField.Value;
            t = value;

            app.StatusmeldungTextArea.Value=[app.StatusmeldungTextArea.Value;'t gesetzt'];
            
        end

        % Value changed function: LngeEditField
        function LngeEditFieldValueChanged(app, event)
         global L;
            
            value = app.LngeEditField.Value;
            L = value;

            app.StatusmeldungTextArea.Value=[app.StatusmeldungTextArea.Value;'L gesetzt'];
            
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            clear global
            
            app.StatuscmeldungTextArea.Value = '';


            cla(app.UIAxes, 'reset');

        end

        % Button pushed function: HinzufgenButton
        function HinzufgenButtonPushed(app, event)
            %global L;
            %global t;
            %global W;
            %global P;
            global i;
            global M;
            global Line;
            global F;
       
       M(1,6) = 25;                                %X1 (X-Koordinate Anfangspunkt)
       M(1,7) = 25;                                %Y1 (Y-Koordinate Anfangspunkt)

       i=app.AusgewhltesElementSpinner.Value;


     % M:
     % gerade Bauteile:
     % M(i,1) = Länge
     % M(i,2) = Dicke Anfang
     % M(i,3) = Dicke Ende
     % M(i,4) = WinkelAnfang
     % M(i,5) = WinkelEnde hier immer gleich Winkel Anfang
     % M(i,6) = X-Koordinate Startpunkt der mittellinie
     % M(i,7) = Y-Koordinate Startpunkt der mittellinie
     % M(i,8) = X-Koordinate Endpunkt der mittellinie
     % M(i,9) = Y-Koordinate Endpunkt der mittellinie
%
     % gebogen:
     % gerade Bauteile:
     % M(i,1) = Radius
     % M(i,2) = Dicke Anfang
     % M(i,3) = Dicke Ende
     % M(i,4) = WinkelAnfang
     % M(i,5) = WinkelEnde
     % M(i,6) = X-Koordinate Startpunkt der mittellinie
     % M(i,7) = Y-Koordinate Startpunkt der mittellinie
     % M(i,8) = X-Koordinate Endpunkt der mittellinie
     % M(i,9) = Y-Koordinate Endpunkt der mittellinie


%% Zeichnen von Bauteilen
   if app.geradeButton.Value == true

       F(app.AusgewhltesElementSpinner.Value,1) = 0;

          % Zeichnen gerader Bauteile bestimmung Matrix
        
            %Definition Matrix
            M(i,1) = app.LngeEditField.Value;           %Länge
            M(i,2) = app.DickeAnfangEditField.Value;    %Dicke
            if app.konstanteDickeButton.Value == true
                M(i,3) = M(i,2);
            elseif app.vernderlicheDickeButton.Value == true
                M(i,3) = app.DickeEndeEditField.Value;
            end
            M(i,4) = app.WinkelEditField.Value*pi/180;  %Winkel Anfang
            M(i,5) = app.WinkelEditField.Value*pi/180;  %Winkel Ende
            %M(1,4) = 25;                                %X1 (X-Koordinate Anfangspunkt)
            %M(1,5) = 25;                                %Y1 (Y-Koordinate Anfangspunkt)
            
   elseif app.gebogenButton.Value == true
            %Zeichnen gerader Bauteile bestimmung Matrix
       F(app.AusgewhltesElementSpinner.Value,1) = 1;

       M(i,1)=app.RadiusEditField.Value;
       M(i,2)=app.DickeAnfangEditField.Value;
       if app.konstanteDickeButton.Value == true
                M(i,3) = M(i,2);
       elseif app.vernderlicheDickeButton.Value == true
                M(i,3) = app.DickeEndeEditField.Value;
       end
       M(i,4)=app.AnfangsWinkelEditField.Value*pi/180;
       M(i,5)=app.EndWinkelEditField.Value*pi/180;
       s(i,:)=linspace(M(i,4),M(i,5),100);
       sd(i,:)=linspace(M(i,2),M(i,3),100);

   end

            %Bestimmung ansetztpunkt nächstes Bauteil
            %alt/neu =1 ist an der Mittellinie
            %alt/neu 2 ist oben
            %alt/neu 3 ist unten
            %immer bei betrachtung von 0 Grad
        if i>1 
         if F(app.AusgewhltesElementSpinner.Value-1,1) == 0   
                if app.mittellinieButton.Value == true % 1 = an der Mittellinie
                    if app.mittellinieButton_2.Value == true
                        M(i,6)=M(i-1,6)+cos(M(i-1,4))*app.PositionSpinner.Value;
                        M(i,7)=M(i-1,7)+sin(M(i-1,4))*app.PositionSpinner.Value;
                    elseif app.untenButton_2.Value == true
                        M(i,6)=M(i-1,6)+cos(M(i-1,4))*app.PositionSpinner.Value-sin(M(i,3))*M(i,2)/2;
                        M(i,7)=M(i-1,7)+sin(M(i-1,4))*app.PositionSpinner.Value+cos(M(i,3))*M(i,2)/2;
                    elseif app.obenButton_2.Value == true
                        M(i,6)=M(i-1,6)+cos(M(i-1,4))*app.PositionSpinner.Value+sin(M(i,4))*M(i,2)/2;
                        M(i,7)=M(i-1,7)+sin(M(i-1,4))*app.PositionSpinner.Value-cos(M(i,4))*M(i,2)/2;
                    end
                elseif app.obenButton.Value == true  % 2 = an der Oberkante/gegen den Uhrzeigersinn
                    if app.mittellinieButton_2.Value == true
                        M(i,6)=M(i-1,6)-sin(M(i-1,4))*M(i-1,3)/2+cos(M(i-1,4))*app.PositionSpinner.Value;
                        M(i,7)=M(i-1,7)+cos(M(i-1,4))*M(i-1,3)/2+sin(M(i-1,4))*app.PositionSpinner.Value;
                    elseif app.untenButton_2.Value == true
                        M(i,6)=M(i-1,6)-sin(M(i-1,4))*M(i-1,3)/2+cos(M(i-1,4))*app.PositionSpinner.Value-sin(M(i,4))*M(i,2)/2;
                        M(i,7)=M(i-1,7)+cos(M(i-1,4))*M(i-1,3)/2+sin(M(i-1,4))*app.PositionSpinner.Value+cos(M(i,4))*M(i,2)/2;
                    elseif app.obenButton_2.Value == true
                        M(i,6)=M(i-1,6)-sin(M(i-1,4))*M(i-1,3)/2+cos(M(i-1,4))*app.PositionSpinner.Value+sin(M(i,4))*M(i,2)/2;
                        M(i,7)=M(i-1,7)+cos(M(i-1,4))*M(i-1,3)/2+sin(M(i-1,4))*app.PositionSpinner.Value-cos(M(i,4))*M(i,2)/2;
                    end
                elseif app.untenButton.Value == true   % 3 = an der Unterkante/im Uhrzeigersinn
                    if app.mittellinieButton_2.Value == true
                        M(i,6)=M(i-1,6)+sin(M(i-1,4))*M(i-1,3)/2+cos(M(i-1,4))*app.PositionSpinner.Value;
                        M(i,7)=M(i-1,7)-cos(M(i-1,4))*M(i-1,3)/2+sin(M(i-1,4))*app.PositionSpinner.Value;
                    elseif app.untenButton_2.Value == true
                        M(i,6)=M(i-1,6)+sin(M(i-1,4))*M(i-1,3)/2+cos(M(i-1,4))*app.PositionSpinner.Value-sin(M(i,4))*M(i,2)/2;
                        M(i,7)=M(i-1,7)-cos(M(i-1,4))*M(i-1,3)/2+sin(M(i-1,4))*app.PositionSpinner.Value+cos(M(i,4))*M(i,2)/2;
                    elseif app.obenButton_2.Value == true
                        M(i,6)=M(i-1,6)+sin(M(i-1,4))*M(i-1,3)/2+cos(M(i-1,4))*app.PositionSpinner.Value+sin(M(i,4))*M(i,2)/2;
                        M(i,7)=M(i-1,7)-cos(M(i-1,4))*M(i-1,3)/2+sin(M(i-1,4))*app.PositionSpinner.Value-cos(M(i,4))*M(i,2)/2;
                    end
                end
         elseif F(app.AusgewhltesElementSpinner.Value-1,1) == 1
              
                if app.mittellinieButton.Value == true % 1 = an der Mittellinie
                    if app.mittellinieButton_2.Value == true
                        M(i,6) = M(i-1,6)+(M(i-1,1)*cos(M(i-1,5)))-M(i-1,1)*cos(M(i-1,4));
                        M(i,7) = M(i-1,7)+(M(i-1,1)*sin(M(i-1,5)))-M(i-1,1)*sin(M(i-1,4));
                    elseif app.untenButton_2.Value == true
                        M(i,6) = M(i-1,6)+(M(i-1,1)*cos(M(i-1,5)))-M(i-1,1)*cos(M(i-1,4))-cos(M(i,5)+pi/2)*M(i,2)/2;
                        M(i,7) = M(i-1,7)+(M(i-1,1)*sin(M(i-1,5)))-M(i-1,1)*sin(M(i-1,4))+sin(M(i,5)+pi/2)*M(i,2)/2;
                    elseif app.obenButton_2.Value == true
                        M(i,6) = M(i-1,6)+(M(i-1,1)*cos(M(i-1,5)))-M(i-1,1)*cos(M(i-1,4))+cos(M(i,5)+pi/2)*M(i,2)/2;
                        M(i,7) = M(i-1,7)+(M(i-1,1)*sin(M(i-1,5)))-M(i-1,1)*sin(M(i-1,4))-sin(M(i,5)+pi/2)*M(i,2)/2;
                    end
                elseif app.obenButton.Value == true   % 2 = an der Oberkante/gegen den Uhrzeigersinn
                    if app.mittellinieButton_2.Value == true
                        M(i,6)=M(i-1,6)+(M(i-1,1)-M(i-1,3)/2)*cos(M(i,5))-M(i-1,1)*cos(M(i-1,4));
                        M(i,7)=M(i-1,7)+(M(i-1,1)-M(i-1,3)/2)*sin(M(i,5))-M(i-1,1)*sin(M(i-1,4));
                    elseif app.untenButton_2.Value == true
                        M(i,6)=M(i-1,6)+(M(i-1,1)-M(i-1,3)/2)*cos(M(i,5))-M(i-1,1)*cos(M(i-1,4))-cos(M(i,5)+pi/2)*M(i,2)/2;
                        M(i,7)=M(i-1,7)+(M(i-1,1)-M(i-1,3)/2)*sin(M(i,5))-M(i-1,1)*sin(M(i-1,4))+sin(M(i,5)+pi/2)*M(i,2)/2;
                    elseif app.obenButton_2.Value == true
                        M(i,6)=M(i-1,6)+(M(i-1,1)-M(i-1,3)/2)*cos(M(i,5))-M(i-1,1)*cos(M(i-1,4))+cos(M(i,5)+pi/2)*M(i,2)/2;
                        M(i,7)=M(i-1,7)+(M(i-1,1)-M(i-1,3)/2)*sin(M(i,5))-M(i-1,1)*sin(M(i-1,4))-sin(M(i,5)+pi/2)*M(i,2)/2;
                    end
                elseif app.untenButton.Value == true  % 3 = an der Unterkante/im Uhrzeigersinn
                    if app.mittellinieButton_2.Value == true
                        M(i,6)=M(i-1,6)+(M(i-1,1)+M(i-1,3)/2)*cos(M(i,5))-M(i-1,1)*cos(M(i-1,4));
                        M(i,7)=M(i-1,7)+(M(i-1,1)+M(i-1,3)/2)*sin(M(i,5))-M(i-1,1)*sin(M(i-1,4));
                    elseif app.untenButton_2.Value == true
                        M(i,6)=M(i-1,6)+(M(i-1,1)+M(i-1,3)/2)*cos(M(i,5))-M(i-1,1)*cos(M(i-1,4))-cos(M(i,5)+pi/2)*M(i,2)/2;
                        M(i,7)=M(i-1,7)+(M(i-1,1)+M(i-1,3)/2)*sin(M(i,5))-M(i-1,1)*sin(M(i-1,4))+sin(M(i,5)+pi/2)*M(i,2)/2;
                    elseif app.obenButton_2.Value == true
                        M(i,6)=M(i-1,6)+(M(i-1,1)+M(i-1,3)/2)*cos(M(i,5))-M(i-1,1)*cos(M(i-1,4))+cos(M(i,5)+pi/2)*M(i,2)/2;
                        M(i,7)=M(i-1,7)+(M(i-1,1)+M(i-1,3)/2)*sin(M(i,5))-M(i-1,1)*sin(M(i-1,4))-sin(M(i,5)+pi/2)*M(i,2)/2;
                    end
                end
          
               
            end

        end


if app.geradeButton.Value == true

            
                M(i,8) = M(i,6)+cos(M(i,4))*M(i,1);         %X2 (X_Koordinate Endpunkt)
                M(i,9) = M(i,5)+sin(M(i,4))*M(i,1);         %Y2 (Y-Koordinate Endpunkt)


            app.StatusmeldungTextArea.Value=[app.StatusmeldungTextArea.Value;'M gesetzt'];

            % Zeichnen des Bauteils ab Mittellinie
            %lange linie unten
            x=[M(i,6)+sin(M(i,4))*M(i,2)/2 M(i,6)+sin(M(i,4))*M(i,3)/2+cos(M(i,4))*M(i,1)];
            y=[M(i,7)-cos(M(i,4))*M(i,2)/2 M(i,7)-cos(M(i,4))*M(i,3)/2+sin(M(i,4))*M(i,1)];
            Line(i,1) = line(app.UIAxes, x, y);
            %lange linie oben
            x2=[M(i,6)-sin(M(i,4))*M(i,2)/2 M(i,6)-sin(M(i,4))*M(i,3)/2+cos(M(i,4))*M(i,1)];
            y2=[M(i,7)+cos(M(i,4))*M(i,2)/2 M(i,7)+cos(M(i,4))*M(i,3)/2+sin(M(i,4))*M(i,1)];
            Line(i,2) = line(app.UIAxes, x2, y2);
            %kurze Linie Anfang
            x3=[M(i,6)+sin(M(i,4))*M(i,2)/2 M(i,6)-sin(M(i,4))*M(i,2)/2];
            y3=[M(i,7)-cos(M(i,4))*M(i,2)/2 M(i,7)+cos(M(i,4))*M(i,2)/2];
            Line(i,3) = line(app.UIAxes, x3, y3);
            x4=[M(i,6)+sin(M(i,4))*M(i,3)/2+cos(M(i,4))*M(i,1) M(i,6)-sin(M(i,4))*M(i,3)/2+cos(M(i,4))*M(i,1)];
            y4=[M(i,7)-cos(M(i,4))*M(i,3)/2+sin(M(i,4))*M(i,1) M(i,7)+cos(M(i,4))*M(i,3)/2+sin(M(i,4))*M(i,1)];
            Line(i,4) = line(app.UIAxes, x4, y4);
            x5 = [M(i,6) M(i,6)+cos(M(i,4))*M(i,1)];
            y5 = [M(i,7) M(i,7)+sin(M(i,4))*M(i,1)];
            Line(i,5) = line(app.UIAxes, x5, y5, 'LineStyle','--');


            hold(app.UIAxes, 'on');                        %Sorgt dafür ,dass Zeichnung nicht gelöscht wird bei weiteren Zeichnungen in die UIAxes
            app.AusgewhltesElementSpinner.Value = i+1;     %Spinner geht nach Hinzufügen automatisch ein weiter
elseif app.gebogenButton.Value == true
    
                M(i,8) = M(i,6)+(M(i,1)*cos(M(i,5)))-M(i,1)*cos(M(i,4));        %X2 (X_Koordinate Endpunkt)
                M(i,9) = M(i,7)+(M(i,1)*sin(M(i,5)))-M(i,1)*sin(M(i,4));         %Y2 (Y-Koordinate Endpunkt)
        %gebogene Bauteile ohne Veränderliche dicken    
     %
     %  x1 = M(i,6)+(M(i,1)-M(i,2)/2)*cos(s(i,:))-M(i,1)*cos(M(i,4));
     %  y1 = M(i,7)+(M(i,1)-M(i,2)/2)*sin(s(i,:))-M(i,1)*sin(M(i,4));
     %  Line(i,1)=plot(app.UIAxes,x1,y1,'k')
     %  hold(app.UIAxes, 'on'); 
     %  x2 = M(i,6)+(M(i,1)+M(i,2)/2)*cos(s(i,:))-M(i,1)*cos(M(i,4));
     %  y2 = M(i,7)+(M(i,1)+M(i,2)/2)*sin(s(i,:))-M(i,1)*sin(M(i,4));
     %  Line(i,2)=plot(app.UIAxes,x2,y2,'k')
     %  x3 = [M(i,6)-sin(M(i,4)+pi/2)*M(i,2)/2 M(i,6)+sin(M(i,4)+pi/2)*M(i,2)/2]
     %  y3 = [M(i,7)-cos(M(i,4)+pi/2)*M(i,2)/2 M(i,7)+cos(M(i,4)+pi/2)*M(i,2)/2]
     %  Line(i,3)=line(app.UIAxes, x3, y3)
     %  x4 = [M(i,6)+(M(i,1)*cos(M(i,5)))-M(i,1)*cos(M(i,4))-sin(M(i,5)+pi/2)*M(i,2)/2 M(i,6)+(M(i,1)*cos(M(i,5)))-M(i,1)*cos(M(i,4))+sin(M(i,5)+pi/2)*M(i,2)/2]
     %  y4 = [M(i,7)+(M(i,1)*sin(M(i,5)))-M(i,1)*sin(M(i,4))-cos(M(i,5)+pi/2)*M(i,2)/2 M(i,7)+(M(i,1)*sin(M(i,5)))-M(i,1)*sin(M(i,4))+cos(M(i,5)+pi/2)*M(i,2)/2]
     %  Line(i,4)=line(app.UIAxes, x4, y4)
     %  x5 = M(i,6)+(M(i,1)*cos(s(i,:)))-M(i,1)*cos(M(i,4));
     %  y5 = M(i,7)+(M(i,1)*sin(s(i,:)))-M(i,1)*sin(M(i,4));
     %  Line(i,5)=plot(app.UIAxes,x5,y5,'k--')


     %gebogene Bauteile mit veränderlichen Dicke
       x1 = M(i,6)+(M(i,1)-sd(i,:)/2).*cos(s(i,:))-M(i,1)*cos(M(i,4));
       y1 = M(i,7)+(M(i,1)-sd(i,:)/2).*sin(s(i,:))-M(i,1)*sin(M(i,4));
       Line(i,1)=plot(app.UIAxes,x1,y1,'k')
       hold(app.UIAxes, 'on'); 
       x2 = M(i,6)+(M(i,1)+sd(i,:)/2).*cos(s(i,:))-M(i,1)*cos(M(i,4));
       y2 = M(i,7)+(M(i,1)+sd(i,:)/2).*sin(s(i,:))-M(i,1)*sin(M(i,4));
       Line(i,2)=plot(app.UIAxes,x2,y2,'k')
       x3 = [M(i,6)-sin(M(i,4)+pi/2)*M(i,2)/2 M(i,6)+sin(M(i,4)+pi/2)*M(i,2)/2]
       y3 = [M(i,7)-cos(M(i,4)+pi/2)*M(i,2)/2 M(i,7)+cos(M(i,4)+pi/2)*M(i,2)/2]
       Line(i,3)=line(app.UIAxes, x3, y3)
       x4 = [M(i,6)+(M(i,1)*cos(M(i,5)))-M(i,1)*cos(M(i,4))-sin(M(i,5)+pi/2)*M(i,3)/2 M(i,6)+(M(i,1)*cos(M(i,5)))-M(i,1)*cos(M(i,4))+sin(M(i,5)+pi/2)*M(i,3)/2]
       y4 = [M(i,7)+(M(i,1)*sin(M(i,5)))-M(i,1)*sin(M(i,4))-cos(M(i,5)+pi/2)*M(i,3)/2 M(i,7)+(M(i,1)*sin(M(i,5)))-M(i,1)*sin(M(i,4))+cos(M(i,5)+pi/2)*M(i,3)/2]
       Line(i,4)=line(app.UIAxes, x4, y4)
       x5 = M(i,6)+(M(i,1)*cos(s(i,:)))-M(i,1)*cos(M(i,4));
       y5 = M(i,7)+(M(i,1)*sin(s(i,:)))-M(i,1)*sin(M(i,4));
       Line(i,5)=plot(app.UIAxes,x5,y5,'k--')


       hold(app.UIAxes, 'on');                        %Sorgt dafür ,dass Zeichnung nicht gelöscht wird bei weiteren Zeichnungen in die UIAxes
       app.AusgewhltesElementSpinner.Value = i+1;     %Spinner geht nach Hinzufügen automatisch ein weiter


   end


       uit = uitable("Data",F); %Ausgabe von M (nur fürs Programmieren)
       

        end

        % Size changed function: Tab
        function TabSizeChanged(app, event)
            position = app.Tab.Position;
            
        end

        % Callback function
        function AusgewhltesElementSpinnerValueChanged(app, event)
           
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             %Reset: Zeichen-Programm neu starten
            
            global Line;
             global M;
             global L;
             global W;
             global t;
           
             delete(Line); %Löschen der Zeichnung 
             % Zurücksetzen der Variablen
             clear M;
             clear t;
             clear W;
             clear L;
             % Zurücksetzen der Benutzeroberfläche
             app.AusgewhltesElementSpinner.Value = 1;
             app.AnsetzpunktaltSpinner.Value = 1;
             app.AnsetzpunktneuSpinner.Value = 1;
             app.PositionSpinner.Value = 0;


        end

        % Value changed function: AusgewhltesElementSpinner
        function AusgewhltesElementSpinnerValueChanged2(app, event)
            global i;
            value = app.AusgewhltesElementSpinner.Value;
            i=value;
            app.StatusmeldungTextArea.Value=[app.StatusmeldungTextArea.Value;'i gesetzt'];
        end

        % Button pushed function: EntfernenButton
        function EntfernenButtonPushed(app, event)
            %Entfernen:  Ausgewähltes Bauteil löschen
            
            global Line;
            global M;
            global i;

            i=app.PositionSpinner.Value;
            
            %Löschen des Bauteils
            delete(Line(i,1));
            delete(Line(i,2));
            delete(Line(i,3));
            delete(Line(i,4));
            delete(Line(i,5));

            %Zurücksetzen der Variablen
            M(i,:) = [];
            Line(i,:)=[];
            
            %Spinner -1
            if app.AusgewhltesElementSpinner.Value >1
            app.AusgewhltesElementSpinner.Value = app.AusgewhltesElementSpinner.Value-1;
            end


        end

        % Button pushed function: BerechnenButton
        function BerechnenButtonPushed(app, event)
      %% Schwerpunktberechnung (bisher nur Rechecksegmente)
            
       global M;
       global i;
       SumAx = 0;
       SumAy = 0;
       SumA = 0;

         uit = uitable("Data",M);    %Als kontrolle beim Programmieren (wird nachher gelöscht)

       %Berechnung Schwerpunkt einzelner Flächen
       % FS Flächenschwerpunkt einzelner Komponenten
       %GS Gesamtflächenschwerpunkt

       for zaehler=1:size(M,1)
           FS(zaehler,1) = (M(zaehler,8)-M(zaehler,6))/2+M(zaehler,6);      %X-Komponente FS-koordinate
           FS(zaehler,3) = FS(zaehler,1)-M(1,6);           %X-Komponente FS- in allgemeiner Form (ohne startpunkt im Diagramm)
           FS(zaehler,2) = (M(zaehler,9)-M(zaehler,7))/2+M(zaehler,7);      %Y-Komponente FS-koordinate
           FS(zaehler,4) = FS(zaehler,2)-M(1,6);           %Y-Komponente FS- in allgemeiner Form (ohne startpunkt im Diagramm)
           %app.FlchenschwerpunkteTextArea.Value=[app.FlchenschwerpunkteTextArea.Value;'Flächenschwerpunkt Komponente',zaehler ,':', FS(zaehler,3) ,',' ,FS(zaehler,4)];
           plot(app.UIAxes, FS(zaehler,1), FS(zaehler,2), 'g-o'); % !! zeichnet das irgendwie noch nicht!!
           hold(app.UIAxes, 'on');
       end

      for zaehler=1:size(M,1)
          SumAx = SumAx + FS(zaehler,3)*M(zaehler,1)*M(zaehler,2)    % Summe Ai mal der Xkomponente
          SumAy = SumAy + FS(zaehler,4)*M(zaehler,1)*M(zaehler,2)    % Summe Ai mal der Ykomponente
          SumA = SumA + M(zaehler,1)*M(zaehler,2)                    % Summe Flächeninhalte
      end

     GSx = SumAx/SumA;      %Gesamtschwerpunkt x Komponente allgemein
     GSy = SumAy/SumA;      %Gesamtschwerpunkt y Komponente allgemein
     GS(1) = GSx+M(1,6);    %Gesamtschwerpunkt X Komponente als Koordinate (für das Einzeichnen ins Bauteil)
     GS(2) = GSy+M(1,7);    %Gesamtschwerpunkt Y Komponente als Koordinate (für das Einzeichnen ins Bauteil)
      %app.FlchenschwerpunkteTextArea.Value=[app.FlchenschwerpunkteTextArea.Value;'Gesamt-Flächenschwerpunkt' ,GSx ,',',GSy];
      app.GesamtflchenschwerpunktxEditField.Value= GSx;     % GSx ausgeben
      app.GesamtflchenschwerpunktyEditField.Value= GSy;     %GSy ausgeben
      plot(app.UIAxes, GS(1), GS(2), 'r-o');
      hold(app.UIAxes, 'on');

        
       uit = uitable("Data",FS); %Ausgabe von M (nur fürs Programmieren)

       %% Flächenträgheitsmoment
% ACHTUNG!!!!!!!!! hier ist optimierungsspielraum, die transformation ins
% hauptachsensystem ist nur notwendig, wenn es tatsächlich balken mit einem
% winkel abweichend von 0 90 180 270 oder 360 grad
% gibt!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
% oder ein kreissegment
%           i = index
%             M(i,1)%Länge
%             M(i,2)%Dicke
%             M(i,4)%Winkel
%             M(i,6)%X1 (X-Koordinate Anfangspunkt)
%             M(i,7)%Y1 (Y-Koordinate Anfangspunkt)
%             M(i,8)%X2 (X_Koordinate Endpunkt)
%             M(i,9)%Y2 (Y-Koordinate Endpunkt)
for zaehler=1:size(M,1)
    Isx = (M(zaehler,1)^3*M(zaehler,2))/12;                      % lokales FTM bezogen auf segment koordinatensystem
    Isy = (M(zaehler,1)*M(zaehler,2)^3)/12;      	            %
    if  F(zaehler,1) == 0
        I(zaehler,1) = 1/2*((Isx+Isy)+(Isx-Isy)*cos(2*M(zaehler,4)));                           % Isx transformiert in gesamtkoordinatensystem
        I(zaehler,2) = 1/2*((Isx+Isy)-(Isx-Isy)*cos(2*M(zaehler,4)));                           % Isy
        I(zaehler,3) = -1/2*(Isx-Isy)*sin(2*M(zaehler,4));                                      % Isxy
    else 
        if F(zaehler,1) == 1
        disp("kreissegment wip")
        else
        disp("ERROR - invalid geometry -")
        end
    end
    %% Was bedeutet M(zaehler,8) ?????????
    %gesamt FTM in globalem Koordinatensystem
    sumFTMx = sumFTMx + I(zaehler,1) + laageyfsp^2 * M(zaehler,8);
    sumFTMy = sumFTMy + I(zaehler,2) + laagexfsp^2 * M(zaehler,8);
    sumFTMxy = sumFTMxy + I(zaehler,3) + laagexfsp * laageyfsp * M(zaehler,8);
end
%berechnung der Hauptflaechentreagheitsmomente
Ihauptx = (sumFTMy+sumFTMx)/2+sqrt(((sumFTMx-sumFTMy)/2)^2+sumFTMxy^2)
Ihaupty = (sumFTMy+sumFTMx)/2-sqrt(((sumFTMx-sumFTMy)/2)^2+sumFTMxy^2)
if Ihaupty<Ihauptx
    temp=Ihaupty;
    Ihaupty=Ihauptx;
    Ihauptx=temp;
end
%berechnung der Hauptachsenverschiebung
phi0= (arctan((2*sumFTMxy)/sumFTMx-sumFTMy))/2;
% Darstellung der hauptachsen währe ganz nett(und nötig)
%% schubflussverteilung


        end

        % Selection changed function: FormButtonGroup
        function FormButtonGroupSelectionChanged(app, event)
            selectedButton = app.FormButtonGroup.SelectedObject;

               global F;
                %An und Ausblenden von Eingabefelder bei Eingabe an der ButtonGroup

             if app.geradeButton.Value == true;
                app.StatusmeldungTextArea.Value='F = 0';
                %gerade
                app.LngeEditField.Visible = 'on';
                app.LngeEditFieldLabel.Visible = 'on';
                %app.DickeAnfangEditField.Visible = 'on';
                %app.DickeAnfangEditFieldLabel.Visible = 'on';
                %app.DickeEndeEditField.Visible = 'on';
                %app.DickeEndeEditFieldLabel.Visible = 'on';
                app.WinkelEditField.Visible = 'on';
                app.WinkelEditFieldLabel.Visible = 'on';
                %app.AusgewhltesElementSpinner.Visible = 'on';
                %app.AusgewhltesElementSpinnerLabel.Visible = 'on';
                %app.AnsetzpunktaltSpinner.Visible = 'on';
                %app.AnsetzpunktaltSpinnerLabel.Visible = 'on';
                %app.PositionSpinner.Visible = 'on';
                %app.PositionSpinnerLabel.Visible = 'on';
                %app.AnsetzpunktneuSpinner.Visible = 'on';
                %app.AnsetzpunktneuSpinnerLabel .Visible= 'on';
                %gebogen
                app.RadiusEditField.Visible = 'off';
                app.RadiusEditFieldLabel.Visible = 'off';
                app.AnfangsWinkelEditField.Visible = 'off';
                app.AnfangsWinkelEditFieldLabel.Visible = 'off';
                app.EndWinkelEditField.Visible = 'off';
                app.EndWinkelEditFieldLabel.Visible = 'off';
                
            elseif app.gebogenButton.Value == true;
                app.StatusmeldungTextArea.Value='F = 1';

                app.LngeEditField.Visible = 'off';
                app.LngeEditFieldLabel.Visible = 'off';
                %app.DickeAnfangEditField.Visible = 'off';
                %app.DickeAnfangEditFieldLabel.Visible = 'off';
                %app.DickeEndeEditField.Visible = 'off';
                %app.DickeEndeEditFieldLabel.Visible = 'off';
                app.WinkelEditField.Visible = 'off';
                app.WinkelEditFieldLabel.Visible = 'off';
                %app.AusgewhltesElementSpinner.Visible = 'off';
                %app.AusgewhltesElementSpinnerLabel.Visible = 'off';
                %app.AnsetzpunktaltSpinner.Visible = 'off';
                %app.AnsetzpunktaltSpinnerLabel.Visible = 'off';
                %app.PositionSpinner.Visible = 'off';
                %app.PositionSpinnerLabel.Visible = 'off';
                %app.AnsetzpunktneuSpinner.Visible = 'off';
                %app.AnsetzpunktneuSpinnerLabel.Visible = 'off';
                %gebogen
                app.RadiusEditField.Visible = 'on';
                app.RadiusEditFieldLabel.Visible = 'on';
                app.AnfangsWinkelEditField.Visible = 'on';
                app.AnfangsWinkelEditFieldLabel.Visible = 'on';
                app.EndWinkelEditField.Visible = 'on';
                app.EndWinkelEditFieldLabel.Visible = 'on';

            end
            
        end

        % Value changed function: PositionSpinner
        function PositionSpinnerValueChanged(app, event)
           % value = app.PositionSpinner.Value;
           % global M;
           % global F;
           % 
           % if size(M,1)>=1
%
           %     if F(app.AusgewhltesElementSpinner.Value) == 0
%
           %     Px = M(app.AusgewhltesElementSpinner.Value,4)+cos(M(app.AusgewhltesElementSpinner.Value,3))*app.PositionSpinner.Value;         %X2 (X_Koordinate Endpunkt)
           %     Py = M(app.AusgewhltesElementSpinner.Value,5)+sin(M(app.AusgewhltesElementSpinner.Value,3))*app.PositionSpinner.Value;         %Y2 (Y-Koordinate Endpunkt)
%
           %     plot(Px,Py,'o');
           %     end
%Funktiniert nicht!!!!!!!!


        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            if app.konstanteDickeButton.Value == true
                app.DickeEndeEditField.Visible = "off";
                app.DickeEndeEditFieldLabel.Visible = "off";
            elseif app.vernderlicheDickeButton.Value == true
                app.DickeEndeEditField.Visible = "on";
                app.DickeEndeEditFieldLabel.Visible = "on";
            end
                

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.SizeChangedFcn = createCallbackFcn(app, @TabSizeChanged, true);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 200];
            app.UIAxes.YLim = [0 200];
            app.FormButtonGroup = uibuttongroup(app.Tab);
            app.FormButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FormButtonGroupSelectionChanged, true);
            app.FormButtonGroup.Title = 'Form';
            app.geradeButton = uiradiobutton(app.FormButtonGroup);
            app.geradeButton.Text = 'gerade';
            app.geradeButton.Value = true;
            app.gebogenButton = uiradiobutton(app.FormButtonGroup);
            app.gebogenButton.Text = 'gebogen';
            app.KraftButton = uiradiobutton(app.FormButtonGroup);
            app.KraftButton.Text = 'Kraft';
            app.EntfernenButton = uibutton(app.Tab, 'push');
            app.EntfernenButton.ButtonPushedFcn = createCallbackFcn(app, @EntfernenButtonPushed, true);
            app.EntfernenButton.Text = '- Entfernen';
            app.ResetButton = uibutton(app.Tab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LngeEditFieldLabel = uilabel(app.Tab);
            app.LngeEditFieldLabel.Text = 'Länge';
            app.LngeEditField = uieditfield(app.Tab, 'numeric');
            app.LngeEditField.ValueChangedFcn = createCallbackFcn(app, @LngeEditFieldValueChanged, true);
            app.LngeEditField.Value = 100;
            app.DickeAnfangEditFieldLabel = uilabel(app.Tab);
            app.DickeAnfangEditFieldLabel.Text = 'DickeAnfang';
            app.DickeAnfangEditField = uieditfield(app.Tab, 'numeric');
            app.DickeAnfangEditField.ValueChangedFcn = createCallbackFcn(app, @DickeAnfangEditFieldValueChanged, true);
            app.DickeAnfangEditField.Value = 10;
            app.WinkelEditFieldLabel = uilabel(app.Tab);
            app.WinkelEditFieldLabel.Text = 'Winkel';
            app.WinkelEditField = uieditfield(app.Tab, 'numeric');
            app.WinkelEditField.ValueChangedFcn = createCallbackFcn(app, @WinkelEditFieldValueChanged, true);
            app.HinzufgenButton = uibutton(app.Tab, 'push');
            app.HinzufgenButton.ButtonPushedFcn = createCallbackFcn(app, @HinzufgenButtonPushed, true);
            app.HinzufgenButton.Text = '+ Hinzufügen';
            app.AusgewhltesElementSpinnerLabel = uilabel(app.Tab);
            app.AusgewhltesElementSpinnerLabel.Text = 'Ausgewähltes Element';
            app.AusgewhltesElementSpinner = uispinner(app.Tab);
            app.AusgewhltesElementSpinner.ValueChangedFcn = createCallbackFcn(app, @AusgewhltesElementSpinnerValueChanged2, true);
            app.AusgewhltesElementSpinner.Value = 1;
            app.PositionSpinnerLabel = uilabel(app.Tab);
            app.PositionSpinnerLabel.Text = 'Position';
            app.PositionSpinner = uispinner(app.Tab);
            app.PositionSpinner.ValueChangedFcn = createCallbackFcn(app, @PositionSpinnerValueChanged, true);
            app.PositionSpinner.Value = 100;
            app.BerechnenButton = uibutton(app.Tab, 'push');
            app.BerechnenButton.ButtonPushedFcn = createCallbackFcn(app, @BerechnenButtonPushed, true);
            app.BerechnenButton.Text = 'Berechnen';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Funktionen', 'FHAachen-logo2010.png');
            app.RadiusEditFieldLabel = uilabel(app.Tab);
            app.RadiusEditFieldLabel.Visible = 'off';
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.Tab, 'numeric');
            app.RadiusEditField.Visible = 'off';
            app.RadiusEditField.Value = 100;
            app.AnfangsWinkelEditFieldLabel = uilabel(app.Tab);
            app.AnfangsWinkelEditFieldLabel.Visible = 'off';
            app.AnfangsWinkelEditFieldLabel.Text = 'AnfangsWinkel';
            app.AnfangsWinkelEditField = uieditfield(app.Tab, 'numeric');
            app.AnfangsWinkelEditField.Visible = 'off';
            app.AnfangsWinkelEditField.Value = -90;
            app.EndWinkelEditFieldLabel = uilabel(app.Tab);
            app.EndWinkelEditFieldLabel.Visible = 'off';
            app.EndWinkelEditFieldLabel.Text = 'EndWinkel';
            app.EndWinkelEditField = uieditfield(app.Tab, 'numeric');
            app.EndWinkelEditField.Visible = 'off';
            app.DickeEndeEditFieldLabel = uilabel(app.Tab);
            app.DickeEndeEditFieldLabel.Visible = 'off';
            app.DickeEndeEditFieldLabel.Text = 'DickeEnde';
            app.DickeEndeEditField = uieditfield(app.Tab, 'numeric');
            app.DickeEndeEditField.Visible = 'off';
            app.DickeEndeEditField.Value = 5;
            app.AnsetztpunktaltButtonGroup = uibuttongroup(app.Tab);
            app.AnsetztpunktaltButtonGroup.Title = 'Ansetztpunkt alt';
            app.obenButton = uiradiobutton(app.AnsetztpunktaltButtonGroup);
            app.obenButton.Text = 'oben';
            app.untenButton = uiradiobutton(app.AnsetztpunktaltButtonGroup);
            app.untenButton.Text = 'unten';
            app.mittellinieButton = uiradiobutton(app.AnsetztpunktaltButtonGroup);
            app.mittellinieButton.Text = 'mittellinie';
            app.mittellinieButton.Value = true;
            app.AnsetztpunktneuButtonGroup = uibuttongroup(app.Tab);
            app.AnsetztpunktneuButtonGroup.Title = 'Ansetztpunkt neu';
            app.obenButton_2 = uiradiobutton(app.AnsetztpunktneuButtonGroup);
            app.obenButton_2.Text = 'oben';
            app.mittellinieButton_2 = uiradiobutton(app.AnsetztpunktneuButtonGroup);
            app.mittellinieButton_2.Text = 'mittellinie';
            app.mittellinieButton_2.Value = true;
            app.untenButton_2 = uiradiobutton(app.AnsetztpunktneuButtonGroup);
            app.untenButton_2.Text = 'unten';
            app.ButtonGroup = uibuttongroup(app.Tab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.konstanteDickeButton = uiradiobutton(app.ButtonGroup);
            app.konstanteDickeButton.Text = 'konstante Dicke';
            app.konstanteDickeButton.Value = true;
            app.vernderlicheDickeButton = uiradiobutton(app.ButtonGroup);
            app.vernderlicheDickeButton.Text = 'veränderliche Dicke';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.StatusmeldungTextAreaLabel = uilabel(app.Tab2);
            app.StatusmeldungTextAreaLabel.Text = 'Statusmeldung';
            app.StatusmeldungTextArea = uitextarea(app.Tab2);
            app.FlchenschwerpunkteTextAreaLabel = uilabel(app.Tab2);
            app.FlchenschwerpunkteTextAreaLabel.Text = 'Flächenschwerpunkte';
            app.FlchenschwerpunkteTextArea = uitextarea(app.Tab2);
            app.GesamtflchenschwerpunktxEditFieldLabel = uilabel(app.Tab2);
            app.GesamtflchenschwerpunktxEditFieldLabel.Text = 'Gesamtflächenschwerpunkt x';
            app.GesamtflchenschwerpunktxEditField = uieditfield(app.Tab2, 'numeric');
            app.GesamtflchenschwerpunktyEditFieldLabel = uilabel(app.Tab2);
            app.GesamtflchenschwerpunktyEditFieldLabel.Text = 'Gesamtflächenschwerpunkt y';
            app.GesamtflchenschwerpunktyEditField = uieditfield(app.Tab2, 'numeric');
            app.Image_2 = uiimage(app.Tab2);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Funktionen', 'FHAachen-logo2010.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end