classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        AnsetzpunktSpinner              matlab.ui.control.Spinner
        AnsetzpunktSpinnerLabel         matlab.ui.control.Label
        PositionSpinner                 matlab.ui.control.Spinner
        PositionSpinnerLabel            matlab.ui.control.Label
        AusgewhltesElementSpinner       matlab.ui.control.Spinner
        AusgewhltesElementSpinnerLabel  matlab.ui.control.Label
        HinzufgenButton                 matlab.ui.control.Button
        WinkelEditField                 matlab.ui.control.NumericEditField
        WinkelEditFieldLabel            matlab.ui.control.Label
        DickeEditField                  matlab.ui.control.NumericEditField
        DickeEditFieldLabel             matlab.ui.control.Label
        LngeEditField                   matlab.ui.control.NumericEditField
        LngeEditFieldLabel              matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        EntfernenButton                 matlab.ui.control.Button
        FormButtonGroup                 matlab.ui.container.ButtonGroup
        gebogenButton                   matlab.ui.control.RadioButton
        geradeButton                    matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        Tab2                            matlab.ui.container.Tab
        StatusmeldungTextArea           matlab.ui.control.TextArea
        StatusmeldungTextAreaLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: FormButtonGroup
        function FormButtonGroupButtonDown(app, event)
            global F File

            if app.geradeButton.Value == true;
                F = 0;
                app.StatusmeldungenTextArea.Value='F = 0';
                %app.PositionSpinner.Visible = 'on';
                %app.AusgewhltesElementSpinner.Visible = 'on';
                %app.HinzufgenButton.Visible = 'on';
                %app.WinkelEditField.Visible = 'on';
                %app.DickeEditField.Visible = 'on';
                %app.LngeEditField.Visible = 'on';
                %app.ResetButton.Visible = 'on';
                %app.EntfernenButton.Visible = 'on';
                %app.UIAxes = 'on';
                %app.PositionSpinnerLabel.Visible = 'on';
                %app.AusgewhltesElementSpinnerLabel.Visible = 'on';
                %app.WinkelEditFieldLabel.Visible = 'on';
                %app.DickeEditFieldLabel.Visible = 'on';
                %app.LngeEditFieldLabel.Visible = 'on';
%
                

            elseif app.gebogenButton.Value == true;
                F = 1;
                app.StatusmeldungenTextArea.Value='F = 1';
                %app.PositionSpinner.Visible = 'on';
                %app.AusgewhltesElementSpinner.Visible = 'on';
                %app.HinzufgenButton.Visible = 'on';
                %app.WinkelEditField.Visible = 'on';
                %app.DickeEditField.Visible = 'on';
                %app.LngeEditField.Visible = 'on';
                %app.ResetButton.Visible = 'on';
                %app.EntfernenButton.Visible = 'on';
                %app.UIAxes = 'on';
                %app.PositionSpinnerLabel.Visible = 'on';
                %app.AusgewhltesElementSpinnerLabel.Visible = 'on';
                %app.WinkelEditFieldLabel.Visible = 'on';
                %app.DickeEditFieldLabel.Visible = 'on';
                %app.LngeEditFieldLabel.Visible = 'on';
%
            end
        end

        % Value changed function: WinkelEditField
        function WinkelEditFieldValueChanged(app, event)
            global W;
            
            value = app.WinkelEditField.Value;
            W = value;

           app.StatusmeldungTextArea.Value=[app.StatusmeldungTextArea.Value;'W gesetzt'];
        end

        % Value changed function: DickeEditField
        function DickeEditFieldValueChanged(app, event)
           global t;
            
            value = app.DickeEditField.Value;
            t = value;

            app.StatusmeldungTextArea.Value=[app.StatusmeldungTextArea.Value;'t gesetzt'];
            
        end

        % Value changed function: LngeEditField
        function LngeEditFieldValueChanged(app, event)
         global L;
            
            value = app.LngeEditField.Value;
            L = value;

            app.StatusmeldungTextArea.Value=[app.StatusmeldungTextArea.Value;'L gesetzt'];
            
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            clear global
            
            app.StatuscmeldungTextArea.Value = '';


            cla(app.UIAxes, 'reset');

        end

        % Button pushed function: HinzufgenButton
        function HinzufgenButtonPushed(app, event)
            %global L;
            %global t;
            %global W;
            %global P;
            global i;
            global M;

            i=app.AusgewhltesElementSpinner.Value;
        
            %Definition Matrix
            M(i,1) = app.LngeEditField.Value;           %Länge
            M(i,2) = app.DickeEditField.Value;          %Dicke
            M(i,3) = app.WinkelEditField.Value*pi/180;  %Winkel
            M(1,4) = 25;                                %X1 (X-Koordinate Anfangspunkt)
            M(1,5) = 25;                                %Y1 (Y-Koordinate Anfangspunkt)
            M(1,6) = M(i,4)+cos(M(i,3))*M(i,1);         %X2 (X_Koordinate Endpunkt)
            M(i,7) = M(i,5)+sin(M(i,3))*M(i,1);         %Y2 (Y-Koordinate Endpunkt)

            % Bestimmung Ansetzpunkt nächstes Bauteil
            %funktioniert aktuell nur bei 45 grad
            if i>1
                if app.AnsetzpunktSpinner.Value == 1      % 1 = an der Mittellinie
                        M(i,4)=M(i-1,4)+cos(M(i-1,3))*app.PositionSpinner.Value;
                        M(i,5)=M(i-1,5)+sin(M(i-1,3))*app.PositionSpinner.Value;
                elseif app.AnsetzpunktSpinner.Value == 2  % 2 = an der Oberkante/gegen den Uhrzeigersinn
                        M(i,4)=M(i-1,4)+cos(M(i-1,3))*app.PositionSpinner.Value-cos(M(i-1,3))*M(i-1,2)/2;
                        M(i,5)=M(i-1,5)+sin(M(i-1,3))*app.PositionSpinner.Value+sin(M(i-1,3))*M(i-1,2)/2;
                elseif app.AnsetzpunktSpinner.Value == 3  % 3 = an der Unterkante/im Uhrzeigersinn
                        M(i,4)=M(i-1,4)+cos(M(i-1,3))*app.PositionSpinner.Value+cos(M(i-1,3))*M(i-1,2)/2;
                        M(i,5)=M(i-1,5)+sin(M(i-1,3))*app.PositionSpinner.Value-sin(M(i-1,3))*M(i-1,2)/2;
                end
            end


            %M(i+1,4)=M(i,4)+cos(M(i,3))*app.PositionSpinner.Value;
            %M(i+1,5)=M(i,5)+sin(M(i,3))*app.PositionSpinner.Value;


            app.StatusmeldungTextArea.Value=[app.StatusmeldungTextArea.Value;'M gesetzt'];


            % Zeichnen des Bauteils
            x=[M(i,4)+sin(M(i,3))*M(i,2)/2 M(i,4)+sin(M(i,3))*M(i,2)/2+cos(M(i,3))*M(i,1)];
            y=[M(i,5)-cos(M(i,3))*M(i,2)/2 M(i,5)-cos(M(i,3))*M(i,2)/2+sin(M(i,3))*M(i,1)];
            line(app.UIAxes, x, y);
            x2=[M(i,4)-sin(M(i,3))*M(i,2)/2 M(i,4)-sin(M(i,3))*M(i,2)/2+cos(M(i,3))*M(i,1)];
            y2=[M(i,5)+cos(M(i,3))*M(i,2)/2 M(i,5)+cos(M(i,3))*M(i,2)/2+sin(M(i,3))*M(i,1)];
            line(app.UIAxes, x2, y2);
            x3=[M(i,4)+sin(M(i,3))*M(i,2)/2 M(i,4)-sin(M(i,3))*M(i,2)/2];
            y3=[M(i,5)-cos(M(i,3))*M(i,2)/2 M(i,5)+cos(M(i,3))*M(i,2)/2];
            line(app.UIAxes, x3, y3);
            x4=[M(i,4)+sin(M(i,3))*M(i,2)/2+cos(M(i,3))*M(i,1) M(i,4)-sin(M(i,3))*M(i,2)/2+cos(M(i,3))*M(i,1)];
            y4=[M(i,5)-cos(M(i,3))*M(i,2)/2+sin(M(i,3))*M(i,1) M(i,5)+cos(M(i,3))*M(i,2)/2+sin(M(i,3))*M(i,1)];
            line(app.UIAxes, x4, y4);
            x5 = [M(i,4) M(i,4)+cos(M(i,3))*M(i,1)];
            y5 = [M(i,5) M(i,5)+sin(M(i,3))*M(i,1)];
            line(app.UIAxes, x5, y5, 'LineStyle','--');


            %rectangle(app.UIAxes, [P(1) P(2) L t]);
            %hold(app.UIAxes, 'on');


        end

        % Size changed function: Tab
        function TabSizeChanged(app, event)
            position = app.Tab.Position;
            
        end

        % Callback function
        function AusgewhltesElementSpinnerValueChanged(app, event)
           
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             clear global
            
            app.StatusmeldungTextArea.Value = '';


            cla(app.UIAxes, 'reset');

        end

        % Value changed function: AusgewhltesElementSpinner
        function AusgewhltesElementSpinnerValueChanged2(app, event)
            global i;
            value = app.AusgewhltesElementSpinner.Value;
            i=value;
            app.StatusmeldungTextArea.Value=[app.StatusmeldungTextArea.Value;'i gesetzt'];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.SizeChangedFcn = createCallbackFcn(app, @TabSizeChanged, true);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 200];
            app.UIAxes.YLim = [0 200];
            app.FormButtonGroup = uibuttongroup(app.Tab);
            app.FormButtonGroup.Title = 'Form';
            app.FormButtonGroup.ButtonDownFcn = createCallbackFcn(app, @FormButtonGroupButtonDown, true);
            app.geradeButton = uiradiobutton(app.FormButtonGroup);
            app.geradeButton.Text = 'gerade';
            app.geradeButton.Value = true;
            app.gebogenButton = uiradiobutton(app.FormButtonGroup);
            app.gebogenButton.Text = 'gebogen';
            app.EntfernenButton = uibutton(app.Tab, 'push');
            app.EntfernenButton.Text = '- Entfernen';
            app.ResetButton = uibutton(app.Tab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LngeEditFieldLabel = uilabel(app.Tab);
            app.LngeEditFieldLabel.Text = 'Länge';
            app.LngeEditField = uieditfield(app.Tab, 'numeric');
            app.LngeEditField.ValueChangedFcn = createCallbackFcn(app, @LngeEditFieldValueChanged, true);
            app.DickeEditFieldLabel = uilabel(app.Tab);
            app.DickeEditFieldLabel.Text = 'Dicke';
            app.DickeEditField = uieditfield(app.Tab, 'numeric');
            app.DickeEditField.ValueChangedFcn = createCallbackFcn(app, @DickeEditFieldValueChanged, true);
            app.WinkelEditFieldLabel = uilabel(app.Tab);
            app.WinkelEditFieldLabel.Text = 'Winkel';
            app.WinkelEditField = uieditfield(app.Tab, 'numeric');
            app.WinkelEditField.ValueChangedFcn = createCallbackFcn(app, @WinkelEditFieldValueChanged, true);
            app.HinzufgenButton = uibutton(app.Tab, 'push');
            app.HinzufgenButton.ButtonPushedFcn = createCallbackFcn(app, @HinzufgenButtonPushed, true);
            app.HinzufgenButton.Text = '+ Hinzufügen';
            app.AusgewhltesElementSpinnerLabel = uilabel(app.Tab);
            app.AusgewhltesElementSpinnerLabel.Text = 'Ausgewähltes Element';
            app.AusgewhltesElementSpinner = uispinner(app.Tab);
            app.AusgewhltesElementSpinner.ValueChangedFcn = createCallbackFcn(app, @AusgewhltesElementSpinnerValueChanged2, true);
            app.AusgewhltesElementSpinner.Value = 1;
            app.PositionSpinnerLabel = uilabel(app.Tab);
            app.PositionSpinnerLabel.Text = 'Position';
            app.PositionSpinner = uispinner(app.Tab);
            app.PositionSpinner.Value = 1;
            app.AnsetzpunktSpinnerLabel = uilabel(app.Tab);
            app.AnsetzpunktSpinnerLabel.Text = 'Ansetzpunkt';
            app.AnsetzpunktSpinner = uispinner(app.Tab);
            app.AnsetzpunktSpinner.Value = 1;
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.StatusmeldungTextAreaLabel = uilabel(app.Tab2);
            app.StatusmeldungTextAreaLabel.Text = 'Statusmeldung';
            app.StatusmeldungTextArea = uitextarea(app.Tab2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end