classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        EndWinkelEditField              matlab.ui.control.NumericEditField
        EndWinkelEditFieldLabel         matlab.ui.control.Label
        AnfangsWinkelEditField          matlab.ui.control.NumericEditField
        AnfangsWinkelEditFieldLabel     matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.NumericEditField
        RadiusEditFieldLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        BerechnenButton                 matlab.ui.control.Button
        AnsetzpunktneuSpinner           matlab.ui.control.Spinner
        AnsetzpunktneuSpinnerLabel      matlab.ui.control.Label
        AnsetzpunktaltSpinner           matlab.ui.control.Spinner
        AnsetzpunktaltSpinnerLabel      matlab.ui.control.Label
        PositionSpinner                 matlab.ui.control.Spinner
        PositionSpinnerLabel            matlab.ui.control.Label
        AusgewhltesElementSpinner       matlab.ui.control.Spinner
        AusgewhltesElementSpinnerLabel  matlab.ui.control.Label
        HinzufgenButton                 matlab.ui.control.Button
        WinkelEditField                 matlab.ui.control.NumericEditField
        WinkelEditFieldLabel            matlab.ui.control.Label
        DickeEditField                  matlab.ui.control.NumericEditField
        DickeEditFieldLabel             matlab.ui.control.Label
        LngeEditField                   matlab.ui.control.NumericEditField
        LngeEditFieldLabel              matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        EntfernenButton                 matlab.ui.control.Button
        FormButtonGroup                 matlab.ui.container.ButtonGroup
        gebogenButton                   matlab.ui.control.RadioButton
        geradeButton                    matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        Tab2                            matlab.ui.container.Tab
        Image_2                         matlab.ui.control.Image
        UITable                         matlab.ui.control.Table
        GesamtflchenschwerpunktyEditField  matlab.ui.control.NumericEditField
        GesamtflchenschwerpunktyEditFieldLabel  matlab.ui.control.Label
        GesamtflchenschwerpunktxEditField  matlab.ui.control.NumericEditField
        GesamtflchenschwerpunktxEditFieldLabel  matlab.ui.control.Label
        FlchenschwerpunkteTextArea      matlab.ui.control.TextArea
        FlchenschwerpunkteTextAreaLabel  matlab.ui.control.Label
        StatusmeldungTextArea           matlab.ui.control.TextArea
        StatusmeldungTextAreaLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear global;

            %Wahrscheinlich nicht notwendig
            %An und Ausblenden von Eingabefelder beim Start
            global F;


             if app.geradeButton.Value == true;
                app.StatusmeldungTextArea.Value='F = 0';
                %gerade
                app.LngeEditField.Visible = 'on';
                app.LngeEditFieldLabel.Visible = 'on';
                %app.DickeEditField.Visible = 'on';
                %app.DickeEditFieldLabel.Visible = 'on';
                app.WinkelEditField.Visible = 'on';
                app.WinkelEditFieldLabel.Visible = 'on';
                %app.AusgewhltesElementSpinner.Visible = 'on';
                %app.AusgewhltesElementSpinnerLabel.Visible = 'on';
                %app.AnsetzpunktaltSpinner.Visible = 'on';
                %app.AnsetzpunktaltSpinnerLabel.Visible = 'on';
                %app.PositionSpinner.Visible = 'on';
                %app.PositionSpinnerLabel.Visible = 'on';
                %app.AnsetzpunktneuSpinner.Visible = 'on';
                %app.AnsetzpunktneuSpinnerLabel .Visible= 'on';
                %gebogen
                app.RadiusEditField.Visible = 'off';
                app.RadiusEditFieldLabel.Visible = 'off';
                app.AnfangsWinkelEditField.Visible = 'off';
                app.AnfangsWinkelEditFieldLabel.Visible = 'off';
                app.EndWinkelEditField.Visible = 'off';
                app.EndWinkelEditFieldLabel.Visible = 'off';


            elseif app.gebogenButton.Value == true;
                app.StatusmeldungTextArea.Value='F = 1';
                %gerade
                app.LngeEditField.Visible = 'off';
                app.LngeEditFieldLabel.Visible = 'off';
                %app.DickeEditField.Visible = 'off';
                %app.DickeEditFieldLabel.Visible = 'off';
                app.WinkelEditField.Visible = 'off';
                app.WinkelEditFieldLabel.Visible = 'off';
                %app.AusgewhltesElementSpinner.Visible = 'off';
                %app.AusgewhltesElementSpinnerLabel.Visible = 'off';
                %app.AnsetzpunktaltSpinner.Visible = 'off';
                %app.AnsetzpunktaltSpinnerLabel.Visible = 'off';
                %app.PositionSpinner.Visible = 'off';
                %app.PositionSpinnerLabel.Visible = 'off';
                %app.AnsetzpunktneuSpinner.Visible = 'off';
                %app.AnsetzpunktneuSpinnerLabel.Visible = 'off';
                %gebogen
                app.RadiusEditField.Visible = 'on';
                app.RadiusEditFieldLabel.Visible = 'on';
                app.AnfangsWinkelEditField.Visible = 'on';
                app.AnfangsWinkelEditFieldLabel.Visible = 'on';
                app.EndWinkelEditField.Visible = 'on';
                app.EndWinkelEditFieldLabel.Visible = 'on';

            end
        end

        % Callback function
        function FormButtonGroupButtonDown(app, event)

        end

        % Value changed function: WinkelEditField
        function WinkelEditFieldValueChanged(app, event)
            global W;
            
            value = app.WinkelEditField.Value;
            W = value;

           app.StatusmeldungTextArea.Value=[app.StatusmeldungTextArea.Value;'W gesetzt'];
        end

        % Value changed function: DickeEditField
        function DickeEditFieldValueChanged(app, event)
           global t;
            
            value = app.DickeEditField.Value;
            t = value;

            app.StatusmeldungTextArea.Value=[app.StatusmeldungTextArea.Value;'t gesetzt'];
            
        end

        % Value changed function: LngeEditField
        function LngeEditFieldValueChanged(app, event)
         global L;
            
            value = app.LngeEditField.Value;
            L = value;

            app.StatusmeldungTextArea.Value=[app.StatusmeldungTextArea.Value;'L gesetzt'];
            
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            clear global
            
            app.StatuscmeldungTextArea.Value = '';


            cla(app.UIAxes, 'reset');

        end

        % Button pushed function: HinzufgenButton
        function HinzufgenButtonPushed(app, event)
            %global L;
            %global t;
            %global W;
            %global P;
            global i;
            global M;
            global Line;
            global F;
       
       M(1,4) = 25;                                %X1 (X-Koordinate Anfangspunkt)
       M(1,5) = 25;                                %Y1 (Y-Koordinate Anfangspunkt)

       i=app.AusgewhltesElementSpinner.Value;

%% Zeichnen von Bauteilen
   if app.geradeButton.Value == true

       F(app.AusgewhltesElementSpinner.Value,1) = 0;

          % Zeichnen gerader Bauteile bestimmung Matrix
        
            %Definition Matrix
            M(i,1) = app.LngeEditField.Value;           %Länge
            M(i,2) = app.DickeEditField.Value;          %Dicke
            M(i,3) = app.WinkelEditField.Value*pi/180;  %Winkel
            %M(1,4) = 25;                                %X1 (X-Koordinate Anfangspunkt)
            %M(1,5) = 25;                                %Y1 (Y-Koordinate Anfangspunkt)
            
   elseif app.gebogenButton.Value == true
            %Zeichnen gerader Bauteile bestimmung Matrix
       F(app.AusgewhltesElementSpinner.Value,1) = 1;

       M(i,1)=app.RadiusEditField.Value;
       M(i,2)=app.DickeEditField.Value;
       M(i,3)=app.AnfangsWinkelEditField.Value*pi/180;
       M(i,8)=app.EndWinkelEditField.Value*pi/180;
       s(i,:)=linspace(M(i,3),M(i,8),100);

   end

            %Bestimmung ansetztpunkt nächstes Bauteil
            %alt/neu =1 ist an der Mittellinie
            %alt/neu 2 ist oben
            %alt/neu 3 ist unten
            %immer bei betrachtung von 0 Grad
        if i>1 
         if F(app.AusgewhltesElementSpinner.Value-1,1) == 0   
                if app.AnsetzpunktaltSpinner.Value == 1 % 1 = an der Mittellinie
                    if app.AnsetzpunktneuSpinner.Value == 1
                        M(i,4)=M(i-1,4)+cos(M(i-1,3))*app.PositionSpinner.Value;
                        M(i,5)=M(i-1,5)+sin(M(i-1,3))*app.PositionSpinner.Value;
                    elseif app.AnsetzpunktneuSpinner.Value == 3
                        M(i,4)=M(i-1,4)+cos(M(i-1,3))*app.PositionSpinner.Value-sin(M(i,3))*M(i,2)/2;
                        M(i,5)=M(i-1,5)+sin(M(i-1,3))*app.PositionSpinner.Value+cos(M(i,3))*M(i,2)/2;
                    elseif app.AnsetzpunktneuSpinner.Value == 2
                        M(i,4)=M(i-1,4)+cos(M(i-1,3))*app.PositionSpinner.Value+sin(M(i,3))*M(i,2)/2;
                        M(i,5)=M(i-1,5)+sin(M(i-1,3))*app.PositionSpinner.Value-cos(M(i,3))*M(i,2)/2;
                    end
                elseif app.AnsetzpunktaltSpinner.Value == 2  % 2 = an der Oberkante/gegen den Uhrzeigersinn
                    if app.AnsetzpunktneuSpinner.Value == 1
                        M(i,4)=M(i-1,4)-sin(M(i-1,3))*M(i-1,2)/2+cos(M(i-1,3))*app.PositionSpinner.Value;
                        M(i,5)=M(i-1,5)+cos(M(i-1,3))*M(i-1,2)/2+sin(M(i-1,3))*app.PositionSpinner.Value;
                    elseif app.AnsetzpunktneuSpinner.Value == 3
                        M(i,4)=M(i-1,4)-sin(M(i-1,3))*M(i-1,2)/2+cos(M(i-1,3))*app.PositionSpinner.Value-sin(M(i,3))*M(i,2)/2;
                        M(i,5)=M(i-1,5)+cos(M(i-1,3))*M(i-1,2)/2+sin(M(i-1,3))*app.PositionSpinner.Value+cos(M(i,3))*M(i,2)/2;
                    elseif app.AnsetzpunktneuSpinner.Value == 2
                        M(i,4)=M(i-1,4)-sin(M(i-1,3))*M(i-1,2)/2+cos(M(i-1,3))*app.PositionSpinner.Value+sin(M(i,3))*M(i,2)/2;
                        M(i,5)=M(i-1,5)+cos(M(i-1,3))*M(i-1,2)/2+sin(M(i-1,3))*app.PositionSpinner.Value-cos(M(i,3))*M(i,2)/2;
                    end
                elseif app.AnsetzpunktaltSpinner.Value == 3  % 3 = an der Unterkante/im Uhrzeigersinn
                    if app.AnsetzpunktneuSpinner.Value == 1
                        M(i,4)=M(i-1,4)+sin(M(i-1,3))*M(i-1,2)/2+cos(M(i-1,3))*app.PositionSpinner.Value;
                        M(i,5)=M(i-1,5)-cos(M(i-1,3))*M(i-1,2)/2+sin(M(i-1,3))*app.PositionSpinner.Value;
                    elseif app.AnsetzpunktneuSpinner.Value == 3
                        M(i,4)=M(i-1,4)+sin(M(i-1,3))*M(i-1,2)/2+cos(M(i-1,3))*app.PositionSpinner.Value-sin(M(i,3))*M(i,2)/2;
                        M(i,5)=M(i-1,5)-cos(M(i-1,3))*M(i-1,2)/2+sin(M(i-1,3))*app.PositionSpinner.Value+cos(M(i,3))*M(i,2)/2;
                    elseif app.AnsetzpunktneuSpinner.Value == 2
                        M(i,4)=M(i-1,4)+sin(M(i-1,3))*M(i-1,2)/2+cos(M(i-1,3))*app.PositionSpinner.Value+sin(M(i,3))*M(i,2)/2;
                        M(i,5)=M(i-1,5)-cos(M(i-1,3))*M(i-1,2)/2+sin(M(i-1,3))*app.PositionSpinner.Value-cos(M(i,3))*M(i,2)/2;
                    end
                end
         elseif F(app.AusgewhltesElementSpinner.Value-1,1) == 1
              
                if app.AnsetzpunktaltSpinner.Value == 1 % 1 = an der Mittellinie
                    if app.AnsetzpunktneuSpinner.Value == 1
                        M(i,4) = M(i-1,4)+(M(i-1,1)*cos(M(i-1,8)))-M(i-1,1)*cos(M(i-1,3));
                        M(i,5) = M(i-1,5)+(M(i-1,1)*sin(M(i-1,8)))-M(i-1,1)*sin(M(i-1,3));
                    elseif app.AnsetzpunktneuSpinner.Value == 3
                        M(i,4) = M(i-1,4)+(M(i-1,1)*cos(M(i-1,8)))-M(i-1,1)*cos(M(i-1,3))-sin(M(i,8)+pi/2)*M(i,2)/2;
                        M(i,5) = M(i-1,5)+(M(i-1,1)*sin(M(i-1,8)))-M(i-1,1)*sin(M(i-1,3))+cos(M(i,8)+pi/2)*M(i,2)/2;
                    elseif app.AnsetzpunktneuSpinner.Value == 2
                        M(i,4) = M(i-1,4)+(M(i-1,1)*cos(M(i-1,8)))-M(i-1,1)*cos(M(i-1,3))+sin(M(i,8)+pi/2)*M(i,2)/2;
                        M(i,5) = M(i-1,5)+(M(i-1,1)*sin(M(i-1,8)))-M(i-1,1)*sin(M(i-1,3))-cos(M(i,8)+pi/2)*M(i,2)/2;
                    end
                elseif app.AnsetzpunktaltSpinner.Value == 2  % 2 = an der Oberkante/gegen den Uhrzeigersinn
                    if app.AnsetzpunktneuSpinner.Value == 1
                        M(i,4)=M(i-1,4)+(M(i-1,1)-M(i-1,2)/2)*cos(M(i,8))-M(i-1,1)*cos(M(i-1,3));
                        M(i,5)=M(i-1,5)+(M(i-1,1)-M(i-1,2)/2)*sin(M(i,8))-M(i-1,1)*sin(M(i-1,3));
                    elseif app.AnsetzpunktneuSpinner.Value == 3
                        M(i,4)=M(i-1,4)+(M(i-1,1)-M(i-1,2)/2)*cos(M(i,8))-M(i-1,1)*cos(M(i-1,3))-sin(M(i,8)+pi/2)*M(i,2)/2;
                        M(i,5)=M(i-1,5)+(M(i-1,1)-M(i-1,2)/2)*sin(M(i,8))-M(i-1,1)*sin(M(i-1,3))+cos(M(i,8)+pi/2)*M(i,2)/2;
                    elseif app.AnsetzpunktneuSpinner.Value == 2
                        M(i,4)=M(i-1,4)+(M(i-1,1)-M(i-1,2)/2)*cos(M(i,8))-M(i-1,1)*cos(M(i-1,3))+sin(M(i,8)+pi/2)*M(i,2)/2;
                        M(i,5)=M(i-1,5)+(M(i-1,1)-M(i-1,2)/2)*sin(M(i,8))-M(i-1,1)*sin(M(i-1,3))-cos(M(i,8)+pi/2)*M(i,2)/2;
                    end
                elseif app.AnsetzpunktaltSpinner.Value == 3  % 3 = an der Unterkante/im Uhrzeigersinn
                    if app.AnsetzpunktneuSpinner.Value == 1
                        M(i,4)=M(i-1,4)+(M(i-1,1)+M(i-1,2)/2)*cos(M(i,8))-M(i-1,1)*cos(M(i-1,3));
                        M(i,5)=M(i-1,5)+(M(i-1,1)+M(i-1,2)/2)*sin(M(i,8))-M(i-1,1)*sin(M(i-1,3));
                    elseif app.AnsetzpunktneuSpinner.Value == 3
                        M(i,4)=M(i-1,4)+(M(i-1,1)+M(i-1,2)/2)*cos(M(i,8))-M(i-1,1)*cos(M(i-1,3))-sin(M(i,8)+pi/2)*M(i,2)/2;
                        M(i,5)=M(i-1,5)+(M(i-1,1)+M(i-1,2)/2)*sin(M(i,8))-M(i-1,1)*sin(M(i-1,3))+cos(M(i,8)+pi/2)*M(i,2)/2;
                    elseif app.AnsetzpunktneuSpinner.Value == 2
                        M(i,4)=M(i-1,4)+(M(i-1,1)+M(i-1,2)/2)*cos(M(i,8))-M(i-1,1)*cos(M(i-1,3))+sin(M(i,8)+pi/2)*M(i,2)/2;
                        M(i,5)=M(i-1,5)+(M(i-1,1)+M(i-1,2)/2)*sin(M(i,8))-M(i-1,1)*sin(M(i-1,3))-cos(M(i,8)+pi/2)*M(i,2)/2;
                    end
                end
          
               
            end

        end


if app.geradeButton.Value == true

            
                M(i,6) = M(i,4)+cos(M(i,3))*M(i,1);         %X2 (X_Koordinate Endpunkt)
                M(i,7) = M(i,5)+sin(M(i,3))*M(i,1);         %Y2 (Y-Koordinate Endpunkt)


            app.StatusmeldungTextArea.Value=[app.StatusmeldungTextArea.Value;'M gesetzt'];

            % Zeichnen des Bauteils ab Mittellinie
            x=[M(i,4)+sin(M(i,3))*M(i,2)/2 M(i,4)+sin(M(i,3))*M(i,2)/2+cos(M(i,3))*M(i,1)];
            y=[M(i,5)-cos(M(i,3))*M(i,2)/2 M(i,5)-cos(M(i,3))*M(i,2)/2+sin(M(i,3))*M(i,1)];
            Line(i,1) = line(app.UIAxes, x, y);
            x2=[M(i,4)-sin(M(i,3))*M(i,2)/2 M(i,4)-sin(M(i,3))*M(i,2)/2+cos(M(i,3))*M(i,1)];
            y2=[M(i,5)+cos(M(i,3))*M(i,2)/2 M(i,5)+cos(M(i,3))*M(i,2)/2+sin(M(i,3))*M(i,1)];
            Line(i,2) = line(app.UIAxes, x2, y2);
            x3=[M(i,4)+sin(M(i,3))*M(i,2)/2 M(i,4)-sin(M(i,3))*M(i,2)/2];
            y3=[M(i,5)-cos(M(i,3))*M(i,2)/2 M(i,5)+cos(M(i,3))*M(i,2)/2];
            Line(i,3) = line(app.UIAxes, x3, y3);
            x4=[M(i,4)+sin(M(i,3))*M(i,2)/2+cos(M(i,3))*M(i,1) M(i,4)-sin(M(i,3))*M(i,2)/2+cos(M(i,3))*M(i,1)];
            y4=[M(i,5)-cos(M(i,3))*M(i,2)/2+sin(M(i,3))*M(i,1) M(i,5)+cos(M(i,3))*M(i,2)/2+sin(M(i,3))*M(i,1)];
            Line(i,4) = line(app.UIAxes, x4, y4);
            x5 = [M(i,4) M(i,4)+cos(M(i,3))*M(i,1)];
            y5 = [M(i,5) M(i,5)+sin(M(i,3))*M(i,1)];
            Line(i,5) = line(app.UIAxes, x5, y5, 'LineStyle','--');


            hold(app.UIAxes, 'on');                        %Sorgt dafür ,dass Zeichnung nicht gelöscht wird bei weiteren Zeichnungen in die UIAxes
            app.AusgewhltesElementSpinner.Value = i+1;     %Spinner geht nach Hinzufügen automatisch ein weiter
elseif app.gebogenButton.Value == true
    
                M(i,6) = M(i,4)+(M(i,1)*cos(M(i,8)))-M(i,1)*cos(M(i,3));        %X2 (X_Koordinate Endpunkt)
                M(i,7) = M(i,5)+(M(i,1)*sin(M(i,8)))-M(i,1)*sin(M(i,3));         %Y2 (Y-Koordinate Endpunkt)
            
     
       x1 = M(i,4)+(M(i,1)-M(i,2)/2)*cos(s(i,:))-M(i,1)*cos(M(i,3));
       y1 = M(i,5)+(M(i,1)-M(i,2)/2)*sin(s(i,:))-M(i,1)*sin(M(i,3));
       Line(i,1)=plot(app.UIAxes,x1,y1,'k')
       hold(app.UIAxes, 'on'); 
       x2 = M(i,4)+(M(i,1)+M(i,2)/2)*cos(s(i,:))-M(i,1)*cos(M(i,3));
       y2 = M(i,5)+(M(i,1)+M(i,2)/2)*sin(s(i,:))-M(i,1)*sin(M(i,3));
       Line(i,2)=plot(app.UIAxes,x2,y2,'k')
       x3 = [M(i,4)-sin(M(i,3)+pi/2)*M(i,2)/2 M(i,4)+sin(M(i,3)+pi/2)*M(i,2)/2]
       y3 = [M(i,5)-cos(M(i,3)+pi/2)*M(i,2)/2 M(i,5)+cos(M(i,3)+pi/2)*M(i,2)/2]
       Line(i,3)=line(app.UIAxes, x3, y3)
       x4 = [M(i,4)+(M(i,1)*cos(M(i,8)))-M(i,1)*cos(M(i,3))-sin(M(i,8)+pi/2)*M(i,2)/2 M(i,4)+(M(i,1)*cos(M(i,8)))-M(i,1)*cos(M(i,3))+sin(M(i,8)+pi/2)*M(i,2)/2]
       y4 = [M(i,5)+(M(i,1)*sin(M(i,8)))-M(i,1)*sin(M(i,3))-cos(M(i,8)+pi/2)*M(i,2)/2 M(i,5)+(M(i,1)*sin(M(i,8)))-M(i,1)*sin(M(i,3))+cos(M(i,8)+pi/2)*M(i,2)/2]
       Line(i,4)=line(app.UIAxes, x4, y4)
       x5 = M(i,4)+(M(i,1)*cos(s(i,:)))-M(i,1)*cos(M(i,3));
       y5 = M(i,5)+(M(i,1)*sin(s(i,:)))-M(i,1)*sin(M(i,3));
       Line(i,5)=plot(app.UIAxes,x5,y5,'k--')

       hold(app.UIAxes, 'on');                        %Sorgt dafür ,dass Zeichnung nicht gelöscht wird bei weiteren Zeichnungen in die UIAxes
       app.AusgewhltesElementSpinner.Value = i+1;     %Spinner geht nach Hinzufügen automatisch ein weiter


   end


       uit = uitable("Data",M); %Ausgabe von M (nur fürs Programmieren)
       

        end

        % Size changed function: Tab
        function TabSizeChanged(app, event)
            position = app.Tab.Position;
            
        end

        % Callback function
        function AusgewhltesElementSpinnerValueChanged(app, event)
           
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             %Reset: Zeichen-Programm neu starten
            
            global Line;
             global M;
             global L;
             global W;
             global t;
           
             delete(Line); %Löschen der Zeichnung 
             % Zurücksetzen der Variablen
             clear M;
             clear t;
             clear W;
             clear L;
             % Zurücksetzen der Benutzeroberfläche
             app.AusgewhltesElementSpinner.Value = 1;
             app.AnsetzpunktaltSpinner.Value = 1;
             app.AnsetzpunktneuSpinner.Value = 1;
             app.PositionSpinner.Value = 0;


        end

        % Value changed function: AusgewhltesElementSpinner
        function AusgewhltesElementSpinnerValueChanged2(app, event)
            global i;
            value = app.AusgewhltesElementSpinner.Value;
            i=value;
            app.StatusmeldungTextArea.Value=[app.StatusmeldungTextArea.Value;'i gesetzt'];
        end

        % Button pushed function: EntfernenButton
        function EntfernenButtonPushed(app, event)
            %Entfernen:  Ausgewähltes Bauteil löschen
            
            global Line;
            global M;
            global i;

            i=app.PositionSpinner.Value;
            
            %Löschen des Bauteils
            delete(Line(i,1));
            delete(Line(i,2));
            delete(Line(i,3));
            delete(Line(i,4));
            delete(Line(i,5));

            %Zurücksetzen der Variablen
            M(i,:) = [];
            Line(i,:)=[];
            
            %Spinner -1
            if app.AusgewhltesElementSpinner.Value >1
            app.AusgewhltesElementSpinner.Value = app.AusgewhltesElementSpinner.Value-1;
            end


        end

        % Button pushed function: BerechnenButton
        function BerechnenButtonPushed(app, event)
       global M;
       global i;
       SumAx = 0;
       SumAy = 0;
       SumA = 0;

         uit = uitable("Data",M);    %Als kontrolle beim Programmieren (wird nachher gelöscht)

       %Berechnung Schwerpunkt einzelner Flächen
       % FS Flächenschwerpunkt einzelner Komponenten
       %GS Gesamtflächenschwerpunkt

       for zaehler=1:size(M,1)
           FS(zaehler,1) = (M(zaehler,6)-M(zaehler,4))/2+M(zaehler,4);      %X-Komponente FS-koordinate
           FS(zaehler,3) = FS(zaehler,1)-M(1,4);           %X-Komponente FS- in allgemeiner Form (ohne startpunkt im Diagramm)
           FS(zaehler,2) = (M(zaehler,7)-M(zaehler,5))/2+M(zaehler,5);      %Y-Komponente FS-koordinate
           FS(zaehler,4) = FS(zaehler,2)-M(1,4);           %Y-Komponente FS- in allgemeiner Form (ohne startpunkt im Diagramm)
           %app.FlchenschwerpunkteTextArea.Value=[app.FlchenschwerpunkteTextArea.Value;'Flächenschwerpunkt Komponente',zaehler ,':', FS(zaehler,3) ,',' ,FS(zaehler,4)];
           plot(app.UIAxes, FS(zaehler,1), FS(zaehler,2), 'g-o'); % !! zeichnet das irgendwie noch nicht!!
           hold(app.UIAxes, 'on');
       end

      for zaehler=1:size(M,1)
          SumAx = SumAx + FS(zaehler,3)*M(zaehler,1)*M(zaehler,2)    % Summe Ai mal der Xkomponente
          SumAy = SumAy + FS(zaehler,4)*M(zaehler,1)*M(zaehler,2)    % Summe Ai mal der Ykomponente
          SumA = SumA + M(zaehler,1)*M(zaehler,2)                    % Summe Flächeninhalte
      end

     GSx = SumAx/SumA;      %Gesamtschwerpunkt x Komponente allgemein
     GSy = SumAy/SumA;      %Gesamtschwerpunkt y Komponente allgemein
     GS(1) = GSx+M(1,4);    %Gesamtschwerpunkt X Komponente als Koordinate (für das Einzeichnen ins Bauteil)
     GS(2) = GSy+M(1,5);    %Gesamtschwerpunkt Y Komponente als Koordinate (für das Einzeichnen ins Bauteil)
      %app.FlchenschwerpunkteTextArea.Value=[app.FlchenschwerpunkteTextArea.Value;'Gesamt-Flächenschwerpunkt' ,GSx ,',',GSy];
      app.GesamtflchenschwerpunktxEditField.Value= GSx;     % GSx ausgeben
      app.GesamtflchenschwerpunktyEditField.Value= GSy;     %GSy ausgeben
      plot(app.UIAxes, GS(1), GS(2), 'r-o');
      hold(app.UIAxes, 'on');

        
       uit = uitable("Data",FS); %Ausgabe von M (nur fürs Programmieren)
      
%% Flächenträgheitsmoment
% ACHTUNG!!!!!!!!! hier ist optimierungsspielraum, die transformation ins
% hauptachsensystem ist nur notwendig, wenn es tatsächlich balken mit einem
% winkel abweichend von 0 90 180 270 oder 360 grad
% gibt!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
% oder ein kreissegment
%           i = index
%             M(i,1)%Länge
%             M(i,2)%Dicke
%             M(i,3)%Winkel
%             M(i,4)%X1 (X-Koordinate Anfangspunkt)
%             M(i,5)%Y1 (Y-Koordinate Anfangspunkt)
%             M(i,6)%X2 (X_Koordinate Endpunkt)
%             M(i,7)%Y2 (Y-Koordinate Endpunkt)
for zaehler=size(M,1)
    Isx = (M(zaehler,1)^3*M(zaehler,2))/12;                      % lokales FTM bezogen auf segment koordinatensystem
    Isy = (M(zaehler,1)*M(zaehler,2)^3)/12;      	            %
    if  F(zaehler,1) == 0
        I(zaehler,1) = 1/2*((Isx+Isy)+(Isx-Isy)*cos(2*M(zaehler,3)));                           % Isx transformiert in gesamtkoordinatensystem
        I(zaehler,2) = 1/2*((Isx+Isy)-(Isx-Isy)*cos(2*M(zaehler,3)));                           % Isy
        I(zaehler,3) = -1/2*(Isx-Isy)*sin(2*M(zaehler,3));                                      % Isxy
    else 
        if F(zaehler,1) == 1
        disp("kreissegment wip")
        else
        disp("ERROR - invalid geometry -")
        end
    end
    %gesamt FTM in globalem Koordinatensystem
    sumFTMx = sumFTMx + I(zaehler,1) + laageyfsp^2 * M(zaehler,8);
    sumFTMy = sumFTMy + I(zaehler,2) + laagexfsp^2 * M(zaehler,8);
    sumFTMxy = sumFTMxy + I(zaehler,3) + laagexfsp * laageyfsp * M(zaehler,8);
end
%berechnung der Hauptflaechentreagheitsmomente
Ihauptx = (sumFTMy+sumFTMx)/2+sqrt(((sumFTMx-sumFTMy)/2)^2+sumFTMxy^2)
Ihaupty = (sumFTMy+sumFTMx)/2-sqrt(((sumFTMx-sumFTMy)/2)^2+sumFTMxy^2)
if Ihaupty<Ihauptx
    temp=Ihaupty;
    Ihaupty=Ihauptx;
    Ihauptx=temp;
end
%berechnung der Hauptachsenverschiebung
phi0= (arctan((2*sumFTMxy)/sumFTMx-sumFTMy))/2;
% Darstellung der hauptachsen währe ganz nett(und nötig)
%% schubflussverteilung


        end

        % Selection changed function: FormButtonGroup
        function FormButtonGroupSelectionChanged(app, event)
            selectedButton = app.FormButtonGroup.SelectedObject;

               global F;
                %An und Ausblenden von Eingabefelder bei Eingabe an der ButtonGroup

             if app.geradeButton.Value == true;
                app.StatusmeldungTextArea.Value='F = 0';
                %gerade
                app.LngeEditField.Visible = 'on';
                app.LngeEditFieldLabel.Visible = 'on';
                %app.DickeEditField.Visible = 'on';
                %app.DickeEditFieldLabel.Visible = 'on';
                app.WinkelEditField.Visible = 'on';
                app.WinkelEditFieldLabel.Visible = 'on';
                %app.AusgewhltesElementSpinner.Visible = 'on';
                %app.AusgewhltesElementSpinnerLabel.Visible = 'on';
                %app.AnsetzpunktaltSpinner.Visible = 'on';
                %app.AnsetzpunktaltSpinnerLabel.Visible = 'on';
                %app.PositionSpinner.Visible = 'on';
                %app.PositionSpinnerLabel.Visible = 'on';
                %app.AnsetzpunktneuSpinner.Visible = 'on';
                %app.AnsetzpunktneuSpinnerLabel .Visible= 'on';
                %gebogen
                app.RadiusEditField.Visible = 'off';
                app.RadiusEditFieldLabel.Visible = 'off';
                app.AnfangsWinkelEditField.Visible = 'off';
                app.AnfangsWinkelEditFieldLabel.Visible = 'off';
                app.EndWinkelEditField.Visible = 'off';
                app.EndWinkelEditFieldLabel.Visible = 'off';
                
            elseif app.gebogenButton.Value == true;
                app.StatusmeldungTextArea.Value='F = 1';

                app.LngeEditField.Visible = 'off';
                app.LngeEditFieldLabel.Visible = 'off';
                %app.DickeEditField.Visible = 'off';
                %app.DickeEditFieldLabel.Visible = 'off';
                app.WinkelEditField.Visible = 'off';
                app.WinkelEditFieldLabel.Visible = 'off';
                %app.AusgewhltesElementSpinner.Visible = 'off';
                %app.AusgewhltesElementSpinnerLabel.Visible = 'off';
                %app.AnsetzpunktaltSpinner.Visible = 'off';
                %app.AnsetzpunktaltSpinnerLabel.Visible = 'off';
                %app.PositionSpinner.Visible = 'off';
                %app.PositionSpinnerLabel.Visible = 'off';
                %app.AnsetzpunktneuSpinner.Visible = 'off';
                %app.AnsetzpunktneuSpinnerLabel.Visible = 'off';
                %gebogen
                app.RadiusEditField.Visible = 'on';
                app.RadiusEditFieldLabel.Visible = 'on';
                app.AnfangsWinkelEditField.Visible = 'on';
                app.AnfangsWinkelEditFieldLabel.Visible = 'on';
                app.EndWinkelEditField.Visible = 'on';
                app.EndWinkelEditFieldLabel.Visible = 'on';

            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.SizeChangedFcn = createCallbackFcn(app, @TabSizeChanged, true);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 200];
            app.UIAxes.YLim = [0 200];
            app.FormButtonGroup = uibuttongroup(app.Tab);
            app.FormButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FormButtonGroupSelectionChanged, true);
            app.FormButtonGroup.Title = 'Form';
            app.geradeButton = uiradiobutton(app.FormButtonGroup);
            app.geradeButton.Text = 'gerade';
            app.geradeButton.Value = true;
            app.gebogenButton = uiradiobutton(app.FormButtonGroup);
            app.gebogenButton.Text = 'gebogen';
            app.EntfernenButton = uibutton(app.Tab, 'push');
            app.EntfernenButton.ButtonPushedFcn = createCallbackFcn(app, @EntfernenButtonPushed, true);
            app.EntfernenButton.Text = '- Entfernen';
            app.ResetButton = uibutton(app.Tab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LngeEditFieldLabel = uilabel(app.Tab);
            app.LngeEditFieldLabel.Text = 'Länge';
            app.LngeEditField = uieditfield(app.Tab, 'numeric');
            app.LngeEditField.ValueChangedFcn = createCallbackFcn(app, @LngeEditFieldValueChanged, true);
            app.DickeEditFieldLabel = uilabel(app.Tab);
            app.DickeEditFieldLabel.Text = 'Dicke';
            app.DickeEditField = uieditfield(app.Tab, 'numeric');
            app.DickeEditField.ValueChangedFcn = createCallbackFcn(app, @DickeEditFieldValueChanged, true);
            app.DickeEditField.Value = 10;
            app.WinkelEditFieldLabel = uilabel(app.Tab);
            app.WinkelEditFieldLabel.Text = 'Winkel';
            app.WinkelEditField = uieditfield(app.Tab, 'numeric');
            app.WinkelEditField.ValueChangedFcn = createCallbackFcn(app, @WinkelEditFieldValueChanged, true);
            app.HinzufgenButton = uibutton(app.Tab, 'push');
            app.HinzufgenButton.ButtonPushedFcn = createCallbackFcn(app, @HinzufgenButtonPushed, true);
            app.HinzufgenButton.Text = '+ Hinzufügen';
            app.AusgewhltesElementSpinnerLabel = uilabel(app.Tab);
            app.AusgewhltesElementSpinnerLabel.Text = 'Ausgewähltes Element';
            app.AusgewhltesElementSpinner = uispinner(app.Tab);
            app.AusgewhltesElementSpinner.ValueChangedFcn = createCallbackFcn(app, @AusgewhltesElementSpinnerValueChanged2, true);
            app.AusgewhltesElementSpinner.Value = 1;
            app.PositionSpinnerLabel = uilabel(app.Tab);
            app.PositionSpinnerLabel.Text = 'Position';
            app.PositionSpinner = uispinner(app.Tab);
            app.PositionSpinner.Value = 100;
            app.AnsetzpunktaltSpinnerLabel = uilabel(app.Tab);
            app.AnsetzpunktaltSpinnerLabel.Text = 'Ansetzpunkt alt';
            app.AnsetzpunktaltSpinner = uispinner(app.Tab);
            app.AnsetzpunktaltSpinner.Value = 1;
            app.AnsetzpunktneuSpinnerLabel = uilabel(app.Tab);
            app.AnsetzpunktneuSpinnerLabel.Text = 'Ansetzpunkt neu';
            app.AnsetzpunktneuSpinner = uispinner(app.Tab);
            app.AnsetzpunktneuSpinner.Value = 1;
            app.BerechnenButton = uibutton(app.Tab, 'push');
            app.BerechnenButton.ButtonPushedFcn = createCallbackFcn(app, @BerechnenButtonPushed, true);
            app.BerechnenButton.Text = 'Berechnen';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'FHAachen-logo2010.png');
            app.RadiusEditFieldLabel = uilabel(app.Tab);
            app.RadiusEditFieldLabel.Visible = 'off';
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.Tab, 'numeric');
            app.RadiusEditField.Visible = 'off';
            app.RadiusEditField.Value = 100;
            app.AnfangsWinkelEditFieldLabel = uilabel(app.Tab);
            app.AnfangsWinkelEditFieldLabel.Visible = 'off';
            app.AnfangsWinkelEditFieldLabel.Text = 'AnfangsWinkel';
            app.AnfangsWinkelEditField = uieditfield(app.Tab, 'numeric');
            app.AnfangsWinkelEditField.Visible = 'off';
            app.AnfangsWinkelEditField.Value = -90;
            app.EndWinkelEditFieldLabel = uilabel(app.Tab);
            app.EndWinkelEditFieldLabel.Visible = 'off';
            app.EndWinkelEditFieldLabel.Text = 'EndWinkel';
            app.EndWinkelEditField = uieditfield(app.Tab, 'numeric');
            app.EndWinkelEditField.Visible = 'off';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.StatusmeldungTextAreaLabel = uilabel(app.Tab2);
            app.StatusmeldungTextAreaLabel.Text = 'Statusmeldung';
            app.StatusmeldungTextArea = uitextarea(app.Tab2);
            app.FlchenschwerpunkteTextAreaLabel = uilabel(app.Tab2);
            app.FlchenschwerpunkteTextAreaLabel.Text = 'Flächenschwerpunkte';
            app.FlchenschwerpunkteTextArea = uitextarea(app.Tab2);
            app.GesamtflchenschwerpunktxEditFieldLabel = uilabel(app.Tab2);
            app.GesamtflchenschwerpunktxEditFieldLabel.Text = 'Gesamtflächenschwerpunkt x';
            app.GesamtflchenschwerpunktxEditField = uieditfield(app.Tab2, 'numeric');
            app.GesamtflchenschwerpunktyEditFieldLabel = uilabel(app.Tab2);
            app.GesamtflchenschwerpunktyEditFieldLabel.Text = 'Gesamtflächenschwerpunkt y';
            app.GesamtflchenschwerpunktyEditField = uieditfield(app.Tab2, 'numeric');
            app.UITable = uitable(app.Tab2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Image_2 = uiimage(app.Tab2);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'FHAachen-logo2010.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end